classdef hyperviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BtSelect               matlab.ui.control.Button
        BtMax                  matlab.ui.control.Button
        WavelengthSlider       matlab.ui.control.Slider
        WavelengthSliderLabel  matlab.ui.control.Label
        UIAxesSpectrum         matlab.ui.control.UIAxes
        UIAxesWidefield        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        wavelength % Description
        current_img % Description
    end
    
    methods (Access = private)
        
        function display_max(app)
            app.current_img = max(app.data, [], 3);
            app.update_display();
        end
        
        function display_lam(app, lam_)
            [~, lam_] = min(abs(app.wavelength - lam_));
            app.current_img = app.data(:,:,lam_);
            app.update_display();
        end
        
        function display_spectum(app, x, y)
            spect = squeeze(app.data(y, x, :));
            plot(app.UIAxesSpectrum, app.wavelength, spect);
        end
        
        
        function update_display(app)
%             app.log_image();
            imagesc(app.UIAxesWidefield, app.current_img);
            colormap(app.UIAxesWidefield, gray(256));
            axis(app.UIAxesWidefield, 'image');
        end
        
        
        function log_image(app)
            app.current_img = log10(app.current_img);
            app.current_img(isnan(app.current_img)) = -10;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hypercube, lambda)
            app.data = hypercube;
            app.wavelength = lambda*1e9;
            app.WavelengthSlider.Limits = [min(app.wavelength), max(app.wavelength)];
            app.display_max();
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            value = app.WavelengthSlider.Value;
            app.display_lam(value);
        end

        % Button pushed function: BtMax
        function BtMaxButtonPushed(app, event)
            app.display_max();
        end

        % Button pushed function: BtSelect
        function BtSelectButtonPushed(app, event)
            %             roi = app.selectDatapoints(app.UIAxesWidefield);
            
%             fh = figure; imagesc(app.current_img);
%             colormap(gray(256));axis image
            
%             [x, y] = ginputc(1, 'Color', 'w');
%             close(fh)

roi = drawpoint(app.UIAxesWidefield);
x = roi.Position(1);
y = roi.Position(2);
            
            app.display_spectum(round(x), round(y));
            
            
            %             x = roi.Position(1);
            %             y = roi.Position(2);
            x
            y
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesWidefield = uiaxes(app.UIFigure);
            app.UIAxesSpectrum = uiaxes(app.UIFigure);
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.BtMax = uibutton(app.UIFigure, 'push');
            app.BtMax.ButtonPushedFcn = createCallbackFcn(app, @BtMaxButtonPushed, true);
            app.BtMax.Text = 'MAX';
            app.BtSelect = uibutton(app.UIFigure, 'push');
            app.BtSelect.ButtonPushedFcn = createCallbackFcn(app, @BtSelectButtonPushed, true);
            app.BtSelect.Text = 'Select Point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end