classdef hyperviewerZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DepthBFSlider          matlab.ui.control.Slider
        DepthBFSliderLabel     matlab.ui.control.Label
        DepthHCSlider          matlab.ui.control.Slider
        DepthHCSliderLabel     matlab.ui.control.Label
        BtSelect               matlab.ui.control.Button
        BtMax                  matlab.ui.control.Button
        WavelengthSlider       matlab.ui.control.Slider
        WavelengthSliderLabel  matlab.ui.control.Label
        UIAxesBF               matlab.ui.control.UIAxes
        UIAxesSpectrum         matlab.ui.control.UIAxes
        UIAxesWidefield        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        hc_max
        bf % Description
        wavelength % Description
        current_img % Description
        current_bf
        hc_slice = 1
        bf_slice = 1
        max_or_lam = 0
    end
    
    methods (Access = private)
        
        function display_max(app)
            app.current_img = app.hc_max(:,:,app.hc_slice);
            app.current_bf = app.bf(:,:,app.bf_slice);
            app.update_display();
            app.max_or_lam = 0;
        end
        
        function display_lam(app, lam_)
            [~, lam_] = min(abs(app.wavelength - lam_));
            app.current_img = app.data(:,:,lam_,app.hc_slice);
            app.current_bf = app.bf(:,:,app.bf_slice);
            app.update_display();
            app.max_or_lam = lam_;
        end
        
        function display_spectum(app, x, y)
            spect = squeeze(app.data(y, x, :, app.hc_slice));
            plot(app.UIAxesSpectrum, app.wavelength, spect);
        end
        
        
        function update_display(app)
            %app.log_image();
            imagesc(app.UIAxesWidefield, app.current_img);
            colormap(app.UIAxesWidefield, gray(256));
            axis(app.UIAxesWidefield, 'image');
            
            imagesc(app.UIAxesBF, app.current_bf);
            colormap(app.UIAxesBF, gray(256));
            axis(app.UIAxesBF, 'image');
        end
        
        
        function log_image(app)
            app.current_img = log10(app.current_img);
            app.current_img(isnan(app.current_img)) = -10;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hypercube, lambda, bf_stack)
            app.data = hypercube;
            app.hc_max = squeeze(max(hypercube, [], 3));
            app.bf = bf_stack;
            app.wavelength = lambda*1e9;
            app.WavelengthSlider.Limits = [min(app.wavelength), max(app.wavelength)];
            app.DepthBFSlider.Limits = [1, size(bf_stack,3)];
            app.DepthHCSlider.Limits = [1, size(hypercube,4)];
            app.max_or_lam = 0;
            app.display_max();
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            value = app.WavelengthSlider.Value;
            app.display_lam(value);
        end

        % Button pushed function: BtMax
        function BtMaxButtonPushed(app, event)
            app.display_max();
        end

        % Button pushed function: BtSelect
        function BtSelectButtonPushed(app, event)
            %             roi = app.selectDatapoints(app.UIAxesWidefield);
            
%             fh = figure; imagesc(app.current_img);
%             colormap(gray(256));axis image
            
%             [x, y] = ginputc(1, 'Color', 'w');
%             close(fh)

roi = drawpoint(app.UIAxesWidefield);
x = roi.Position(1);
y = roi.Position(2);
            
            app.display_spectum(round(x), round(y));
            
            
            %             x = roi.Position(1);
            %             y = roi.Position(2);
            x
            y
        end

        % Value changed function: DepthBFSlider
        function DepthBFSliderValueChanged(app, event)
            value = app.DepthBFSlider.Value;
            app.bf_slice = round(value);
            if app.max_or_lam == 0
                app.display_max()
            else
                app.display_lam(app.max_or_lam)
            end
        end

        % Value changed function: DepthHCSlider
        function DepthHCSliderValueChanged(app, event)
            value = app.DepthHCSlider.Value;
            app.hc_slice = round(value);
            if app.max_or_lam == 0
                app.display_max()
            else
                app.display_lam(app.max_or_lam)
            end
        end

        % Value changing function: DepthBFSlider
        function DepthBFSliderValueChanging(app, event)
            changingValue = event.Value;
            app.bf_slice = round(changingValue);
            if app.max_or_lam == 0
                app.display_max()
            else
                app.display_lam(app.max_or_lam)
            end
        end

        % Value changing function: DepthHCSlider
        function DepthHCSliderValueChanging(app, event)
            changingValue = event.Value;
            app.hc_slice = round(changingValue);
            if app.max_or_lam == 0
                app.display_max()
            else
                app.display_lam(app.max_or_lam)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesWidefield = uiaxes(app.UIFigure);
            app.UIAxesSpectrum = uiaxes(app.UIFigure);
            app.UIAxesBF = uiaxes(app.UIFigure);
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.BtMax = uibutton(app.UIFigure, 'push');
            app.BtMax.ButtonPushedFcn = createCallbackFcn(app, @BtMaxButtonPushed, true);
            app.BtMax.Text = 'MAX';
            app.BtSelect = uibutton(app.UIFigure, 'push');
            app.BtSelect.ButtonPushedFcn = createCallbackFcn(app, @BtSelectButtonPushed, true);
            app.BtSelect.Text = 'Select Point';
            app.DepthHCSliderLabel = uilabel(app.UIFigure);
            app.DepthHCSliderLabel.Text = 'Depth HC';
            app.DepthHCSlider = uislider(app.UIFigure);
            app.DepthHCSlider.ValueChangedFcn = createCallbackFcn(app, @DepthHCSliderValueChanged, true);
            app.DepthHCSlider.ValueChangingFcn = createCallbackFcn(app, @DepthHCSliderValueChanging, true);
            app.DepthBFSliderLabel = uilabel(app.UIFigure);
            app.DepthBFSliderLabel.Text = 'Depth BF';
            app.DepthBFSlider = uislider(app.UIFigure);
            app.DepthBFSlider.ValueChangedFcn = createCallbackFcn(app, @DepthBFSliderValueChanged, true);
            app.DepthBFSlider.ValueChangingFcn = createCallbackFcn(app, @DepthBFSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperviewerZ(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end