classdef hyperviewer_timelapse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LRButton                       matlab.ui.control.StateButton
        ClearButton                    matlab.ui.control.Button
        SumButton                      matlab.ui.control.StateButton
        ExportButton                   matlab.ui.control.Button
        TrailingSpectraEditField       matlab.ui.control.NumericEditField
        TrailingSpectraEditFieldLabel  matlab.ui.control.Label
        SmoothingEditField             matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel        matlab.ui.control.Label
        ProfileSpectrumButton          matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        SetROIButton                   matlab.ui.control.Button
        MaxButton                      matlab.ui.control.StateButton
        WavelengthSlider               matlab.ui.control.Slider
        WavelengthSliderLabel          matlab.ui.control.Label
        ScanNoSlider                   matlab.ui.control.Slider
        ScanNoSliderLabel              matlab.ui.control.Label
        UIAxesSpect                    matlab.ui.control.UIAxes
        UIAxesLasing                   matlab.ui.control.UIAxes
        UIAxesHyper                    matlab.ui.control.UIAxes
        UIAxesBF                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        H % SSHIViewer Class

        % Flags
        isInitialised % Description

        % Image buffer
        hyper
        bf
        lasing

        % State
        scan_no
        current_roi
        current_spect_pos
        spect_smooth
        spect_buffer
        trailing_spect
    end

    methods (Access = private)
        function InitUI(app)
            % Scan No
            app.ScanNoSlider.Limits = [1, max(app.H.Hyper.n_scans, 2)];
            app.ScanNoSlider.Value = app.ScanNoSlider.Limits(1);
            app.ScanNoSlider.MajorTicks = 1:max(app.H.Hyper.n_scans, 2);
            app.ScanNoSlider.MinorTicks = [];
            app.scan_no = 1;

            % Wavelength
            app.WavelengthSlider.Limits = [app.H.YML.lambda_vector(1) * 1e9, ...
                app.H.YML.lambda_vector(end) * 1e9];
            app.WavelengthSlider.Value = app.WavelengthSlider.Limits(1);
            app.MaxButton.Value = 1;
            
            % Spect
            app.spect_smooth = 0;
            app.trailing_spect = 0;

            app.isInitialised = 1;

            app.ReadImages();
            app.UpdateDisplays();

%             app.isInitialised = 1;
        end

        function ReadImages(app)
            if app.MaxButton.Value
                app.hyper = app.H.Hyper.Read(app.scan_no, 'Max');
            elseif app.SumButton.Value
                app.hyper = app.H.Hyper.Read(app.scan_no, 'Sum');
            else
                app.hyper = app.H.Hyper.Read(app.scan_no);
            end

            if app.LRButton.Value
                app.bf = app.H.BF.Read(app.scan_no, 'LR', app.H.Hyper.data_size(1:2));
                app.lasing = app.H.Lasing.Read(app.scan_no, 'LR', app.H.Hyper.data_size(1:2));
            else
                app.bf = app.H.BF.Read(app.scan_no);
                app.lasing = app.H.Lasing.Read(app.scan_no);
            end
        end

        function UpdateHyper(app)
            if app.MaxButton.Value || app.SumButton.Value
                img = app.hyper;
            else
                [~, i] = min(abs(app.WavelengthSlider.Value - app.H.YML.lambda_vector * 1e9));
                img = app.hyper(:, :, i);
            end

            imagesc(app.UIAxesHyper, img);
            colormap(app.UIAxesHyper, gray(256));
            axis(app.UIAxesHyper, 'image')
        end

        function UpdateBF(app)
            imagesc(app.UIAxesBF, app.bf);
            colormap(app.UIAxesBF, gray(256));
            axis(app.UIAxesBF, 'image')
        end

        function UpdateLasing(app)
            imagesc(app.UIAxesLasing, app.lasing);
            colormap(app.UIAxesLasing, gray(256));
            axis(app.UIAxesLasing, 'image')
        end

        function UpdateDisplays(app)
            app.UpdateHyper();
            app.UpdateBF();
            app.UpdateLasing();
            if app.current_spect_pos
                app.UpdateSpect(app.current_spect_pos);
            end
        end

        function SetROI(app, roi)
            % if roi changed
            if isstruct(roi) || isstruct(app.H.Hyper.ROI)
                app.H.Hyper.SetROI(roi);
                app.H.BF.SetROI(roi);
                app.H.Lasing.SetROI(roi);

                app.ReadImages();
                app.UpdateDisplays();
            end
        end

        function UpdateROI(app, roi)
%             img_size = app.H.BF.data_size;
            img_size = size(app.bf);

            % roi.Position % [xmin, ymin, width, height]
            pos.v = [roi.Position(2), roi.Position(2) + roi.Position(4)];
            pos.h = [roi.Position(1), roi.Position(1) + roi.Position(3)];

            ROI.v = [(pos.v(1) - 1) / (img_size(1) - 1), ...
                (pos.v(2) - 1) / (img_size(1) - 1)];
            ROI.h = [(pos.h(1) - 1) / (img_size(2) - 1), ...
                (pos.h(2) - 1) / (img_size(2) - 1)];

            app.current_roi = ROI;
            app.SetROI(ROI);
        end

        function UpdateSpect(app, pos)
            app.current_spect_pos = pos;
            if pos
                if size(app.hyper, 3) == 1
                    app.hyper = app.H.Hyper.Read(app.scan_no);
                end

                % Spatial smooth spectra
                if app.spect_smooth
                    tmp = app.hyper;
                    tmp = hyperspectral.gaussian_smooth(tmp, ...
                        [app.spect_smooth, app.spect_smooth, 0]);
                    spect = squeeze(tmp(round(pos(2)), round(pos(1)), :));
                else
                    spect = squeeze(app.hyper(round(pos(2)), round(pos(1)), :));
                end
                plot(app.UIAxesSpect, app.H.YML.lambda_vector * 1e9, spect);
                
                % Plot previous spects?
                if ~isempty(app.spect_buffer) && app.trailing_spect > 0
                    hold(app.UIAxesSpect, "on");
                    for p = 1:min(numel(app.spect_buffer), app.trailing_spect)
                        plot(app.UIAxesSpect, app.H.YML.lambda_vector * 1e9, app.spect_buffer{p}, ...
                            "Color", p / app.trailing_spect * [1, 1, 1], "LineStyle", ":");
                    end
                    hold(app.UIAxesSpect, "off");
                end
                
                % Update rolling buffer
                tmp = {};
                tmp{1} = spect;
                if ~isempty(app.spect_buffer) && app.trailing_spect > 0
                    for s = 1:min(numel(app.spect_buffer), app.trailing_spect)
                        tmp{1+s} = app.spect_buffer{s};
                    end
                end
                app.spect_buffer = tmp;
            else
                cla(app.UIAxesSpect);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_path)
            app.isInitialised = 0;
            app.H = SSHIViewer(data_path);
            app.InitUI();
        end

        % Value changed function: ScanNoSlider
        function ScanNoSliderValueChanged(app, event)
            if app.isInitialised
                app.scan_no = round(app.ScanNoSlider.Value);
                app.ReadImages();
                app.UpdateDisplays();
            end
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            if app.isInitialised
                app.UpdateDisplays();
            end
        end

        % Value changed function: MaxButton
        function MaxButtonValueChanged(app, event)
            if app.isInitialised
                if app.MaxButton.Value && app.SumButton.Value
                    app.SumButton.Value = 0;
                else
                    app.UpdateDisplays();
                end
            end
        end

        % Value changed function: SumButton
        function SumButtonValueChanged(app, event)
            if app.isInitialised
                if app.SumButton.Value && app.MaxButton.Value
                    app.MaxButton.Value = 0;
                else
                    app.UpdateDisplays();
                end
            end
        end

        % Value changed function: LRButton
        function LRButtonValueChanged(app, event)
            if app.isInitialised
                app.UpdateDisplays();
            end
        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            app.SetROI(0);
            roi = drawrectangle(app.UIAxesBF);
            app.UpdateROI(roi);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SetROI(0);
        end

        % Button pushed function: ProfileSpectrumButton
        function ProfileSpectrumButtonPushed(app, event)
            roi = drawcrosshair(app.UIAxesHyper);
            app.UpdateSpect(roi.Position);
            delete(roi);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UpdateSpect(0);
        end

        % Value changed function: SmoothingEditField
        function SmoothingEditFieldValueChanged(app, event)
            app.spect_smooth = app.SmoothingEditField.Value;
            app.UpdateSpect(app.current_spect_pos);
        end

        % Value changed function: TrailingSpectraEditField
        function TrailingSpectraEditFieldValueChanged(app, event)
            app.trailing_spect = app.TrailingSpectraEditField.Value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            save_path = uigetdir;
            
            data.hyper = app.hyper;
            data.bf = app.bf;
            data.lasing = app.lasing;
            data.scan_no = app.scan_no;
            data.current_roi = app.current_roi;
            data.current_spect_pos = app.current_spect_pos;
            data.spect_smooth = app.spect_smooth;
            data.spect_buffer = app.spect_buffer;
            data.trailing_spect = app.trailing_spect;
            data.wavelengths = app.H.YML.lambda_vector;

            save(fullfile(save_path, 'export.mat'), "data", '-v7.3');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesBF = uiaxes(app.UIFigure);
            app.UIAxesBF.XTick = [];
            app.UIAxesBF.YTick = [];
            app.UIAxesHyper = uiaxes(app.UIFigure);
            app.UIAxesHyper.XTick = [];
            app.UIAxesHyper.YTick = [];
            app.UIAxesLasing = uiaxes(app.UIFigure);
            app.UIAxesLasing.XTick = [];
            app.UIAxesLasing.YTick = [];
            app.UIAxesSpect = uiaxes(app.UIFigure);
            app.ScanNoSliderLabel = uilabel(app.UIFigure);
            app.ScanNoSliderLabel.Text = 'Scan No';
            app.ScanNoSlider = uislider(app.UIFigure);
            app.ScanNoSlider.Limits = [0 50];
            app.ScanNoSlider.ValueChangedFcn = createCallbackFcn(app, @ScanNoSliderValueChanged, true);
            app.ScanNoSlider.MinorTicks = [];
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [0 50];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.WavelengthSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.MaxButton = uibutton(app.UIFigure, 'state');
            app.MaxButton.ValueChangedFcn = createCallbackFcn(app, @MaxButtonValueChanged, true);
            app.MaxButton.Text = 'Max';
            app.SetROIButton = uibutton(app.UIFigure, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ProfileSpectrumButton = uibutton(app.UIFigure, 'push');
            app.ProfileSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileSpectrumButtonPushed, true);
            app.ProfileSpectrumButton.Text = 'Profile Spectrum';
            app.SmoothingEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothingEditFieldLabel.Text = {'Smoothing'; ''};
            app.SmoothingEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingEditFieldValueChanged, true);
            app.TrailingSpectraEditFieldLabel = uilabel(app.UIFigure);
            app.TrailingSpectraEditFieldLabel.Text = 'Trailing Spectra';
            app.TrailingSpectraEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrailingSpectraEditField.Limits = [0 10];
            app.TrailingSpectraEditField.RoundFractionalValues = 'on';
            app.TrailingSpectraEditField.ValueChangedFcn = createCallbackFcn(app, @TrailingSpectraEditFieldValueChanged, true);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SumButton = uibutton(app.UIFigure, 'state');
            app.SumButton.ValueChangedFcn = createCallbackFcn(app, @SumButtonValueChanged, true);
            app.SumButton.Text = 'Sum';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LRButton = uibutton(app.UIFigure, 'state');
            app.LRButton.ValueChangedFcn = createCallbackFcn(app, @LRButtonValueChanged, true);
            app.LRButton.Text = 'LR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperviewer_timelapse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end